classdef Affichage_Bras < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        ZonedetravailLampLabel  matlab.ui.control.Label
        ZonedetravailLamp       matlab.ui.control.Lamp
        LancerButton            matlab.ui.control.Button
        L1EditFieldLabel        matlab.ui.control.Label
        L1EditField             matlab.ui.control.NumericEditField
        L2EditFieldLabel        matlab.ui.control.Label
        L2EditField             matlab.ui.control.NumericEditField
        XminEditFieldLabel      matlab.ui.control.Label
        XminEditField           matlab.ui.control.NumericEditField
        XmaxEditFieldLabel      matlab.ui.control.Label
        XmaxEditField           matlab.ui.control.NumericEditField
        YminEditFieldLabel      matlab.ui.control.Label
        YminEditField           matlab.ui.control.NumericEditField
        YmaxEditFieldLabel      matlab.ui.control.Label
        YmaxEditField           matlab.ui.control.NumericEditField
        OverrunT1CheckBox       matlab.ui.control.CheckBox
        OverrunT2CheckBox       matlab.ui.control.CheckBox
        Theta2EditFieldLabel    matlab.ui.control.Label
        Theta2EditField         matlab.ui.control.NumericEditField
        Theta1EditFieldLabel    matlab.ui.control.Label
        Theta1EditField         matlab.ui.control.NumericEditField
        L3EditFieldLabel        matlab.ui.control.Label
        L3EditField             matlab.ui.control.NumericEditField
        Theta3EditFieldLabel    matlab.ui.control.Label
        Theta3EditField         matlab.ui.control.NumericEditField
        OverrunT3CheckBox       matlab.ui.control.CheckBox
        RSliderLabel            matlab.ui.control.Label
        RSlider                 matlab.ui.control.Slider
        ZSliderLabel            matlab.ui.control.Label
        ZSlider                 matlab.ui.control.Slider
        LoopButton              matlab.ui.control.Button
        ZonedetravailLamp_2     matlab.ui.control.Lamp
        PASEditFieldLabel       matlab.ui.control.Label
        PASEditField            matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function OA = coords_OA(~, L1, theta1)
            OA = [L1 * cosd(theta1), L1 * sind(theta1)];
        end
        
        function AE = coords_AE(~, L2, theta1, theta2)
            AE = [L2 * cosd(theta2 + theta1), L2 * sind(theta2 + theta1)];
        end
        
        function OE = coords_OE(app, L1, L2, theta1, theta2)
            OE = coords_OA(app, L1, theta1) + coords_AE(app, L2, theta1, theta2);
        end
        
        function EF = coords_EF(~, L3, theta3)
            EF = [L3 * cosd(theta3), L3 * sind(theta3)];
        end
    
        function OF = coords_OF(app, L1, L2, L3, theta1, theta2, theta3)
            OF = coords_OA(app, L1, theta1) + coords_AE(app, L2, theta1, theta2) + coords_EF(app, L3, theta3);
        end
        
        function F = equation(~, x, L1, L2, L3, X, Y)
            F(1) = L1*cosd(x(1)) - L2*cosd(x(2)) - L3*cosd(x(3)) - X;
            F(2) = L1*sind(x(1)) - L2*sind(x(2)) - L3*sind(x(3)) - Y;
            %F(3) = x(2)+x(3) > 300;
        end
    
        function F = equation2R(~, x, L1, L2, X, Y)
            F(1) = L1*cosd(x(1)) + L2*cosd(x(1) + x(2)) - X;
            F(2) = L1*sind(x(1)) + L2*sind(x(1) + x(2)) - Y;
        end
    
        function F = equation3R(~, x, L1, L2, L3, X, Y)
            F(1) = L1*cosd(x(1)) + L2*cosd(x(1) + x(2)) + L3*cosd(x(1) + x(2) + x(3)) - X;
            F(2) = L1*sind(x(1)) + L2*sind(x(1) + x(2)) + L3*sind(x(1) + x(2) + x(3)) - Y;
            F(3) = x(1) < 181;
        end
    end
    

    methods (Access = private)

        % Callback function
        function XSpinnerValueChanged(app, event)

            
        end

        % Button pushed function: LancerButton
        function LancerButtonPushed(app, event)
            % Taille des bras du robot
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            L3 = app.L3EditField.Value;
            
            % Position cible
            R = app.RSlider.Value;
            Z = app.ZSlider.Value;
            
            %Compute R
            %R = sqrt(X^2+Y^2);
            
            E = [R, Z];
            E2 = [R-L1, Z];
            
            % Vérification si dans la zone de travail
            %if (norm(E) < L1 + L2 + L3) && (norm(E) >= 0)
            if ((R > 0) && (Z > 100) && (norm(E) < L1+L2+L3)) || ((Z > -295) && (Z <101) && (R > 160) && (norm(E2) < L2+L3))
                app.ZonedetravailLamp.Color = 'green';
            else
               app.ZonedetravailLamp.Color = 'red';
               return;
            end
            
            % Calcul des thetas qui vont bien
            % theta2 = acosd((norm(E)^2 - L1^2 - L2^2 )/(-2*L1*L2));
            % theta1 = acosd((X - L2*cosd(theta2 - 90))/L1);
            
            x0 = [90, 90, 90];

            x = fsolve(@(x)equation3R(app, x, L1, L2,L3, E(1), E(2)), x0);
                        
            if(x(1) < 0)
                app.ZonedetravailLamp_2.Color = 'red';
                clear x;
                x = fsolve(@(x)equation2R(app, x,L2,L3, E2(1), E2(2)), x0);
                x = [0 x];
            else
                app.ZonedetravailLamp_2.Color = 'green';
            end
            
            theta1 = x(1);
            theta2 = x(2);
            theta3 = x(3);
            
            app.Theta1EditField.Value = theta1;
            app.Theta2EditField.Value = theta1+theta2;
            app.Theta3EditField.Value = theta1+theta2+theta3;
            
            OA = coords_OA(app, L1, theta1);
            OE = coords_OE(app, L1, L2, theta1, theta2);
            
            % Plot basique
            
            % Repère orthonormal
            app.UIAxes.DataAspectRatio = [1 1 1];
            
            % Limites du graphe
            app.UIAxes.XLim = [app.XminEditField.Value app.XmaxEditField.Value];
            app.UIAxes.YLim = [app.YminEditField.Value app.YmaxEditField.Value];
            
            %Plot
            app.UIAxes.cla;
            hold(app.UIAxes,'on')
            
            % Zone atteignable par le premier bras
            % th = 0:pi/50:2*pi;
            % xunit = L1 * cos(th);
            % yunit = L1 * sin(th);
            % plot(app.UIAxes, xunit, yunit);
            
            % Zone 2ème
            % th = 0:pi/50:2*pi;
            % xunit = L2 * cos(th) + X;
            % yunit = L2 * sin(th) + Y;
            % plot(app.UIAxes, xunit, yunit);
            
            % Zone du robot
            h = fill(app.UIAxes, [50,-50,-50,50],[0,0,-185, -185],[ 0.9100 0.4100 0.1700]);
            h.FaceAlpha=1;
            g = fill(app.UIAxes, [160,-160,-160,160],[-185,-185,-295, -295],[ 0.9100 0.4100 0.1700]);
            g.FaceAlpha=1;
            % Position cible
            R_cible = [R, OA(1), OE(1)];
            Z_cible = [Z, OA(2), OE(2)];
            scatter(app.UIAxes, R_cible, Z_cible);
            
            % On trace le bras entier
            R_bras = [0, L1*cosd(theta1), L1*cosd(theta1) + L2*cosd(theta1 + theta2), L1*cosd(theta1) + L2*cosd(theta1 + theta2) + L3*cosd(theta1 + theta2 + theta3) ];
            Z_bras = [0, L1*sind(theta1), L1*sind(theta1) + L2*sind(theta1 + theta2), L1*sind(theta1) + L2*sind(theta1 + theta2) + L3*sind(theta1 + theta2 + theta3) ];
            plot(app.UIAxes, R_bras, Z_bras);
       
        end

        % Button pushed function: LoopButton
        function LoopButtonPushed(app, event)
            x = [0 0 0];
            X_MIN = app.XminEditField.Value;
            X_MAX = app.XmaxEditField.Value;
            Y_MIN = app.YminEditField.Value;
            Y_MAX = app.YmaxEditField.Value;
            PAS = app.PASEditField.Value;
            for j = Y_MIN:PAS:Y_MAX
                for i = X_MIN:PAS:X_MAX
           
                    % Taille des bras du robot
                    L1 = app.L1EditField.Value;
                    L2 = app.L2EditField.Value;
                    L3 = app.L3EditField.Value;
            
                    % Position cible
                    %R = app.RSlider.Value;
                    %Z = app.ZSlider.Value;
                    R = i;
                    Z = j;
            
                    %Compute R
                    %R = sqrt(X^2+Y^2);
            
                    E = [R, Z];
                    E2 = [R-L1, Z];
            
                    % Vérification si dans la zone de travail
                    %if (norm(E) < L1 + L2 + L3) && (norm(E) >= 0)
                    if ((R > 0) && (Z > 100) && (norm(E) < L1+L2+L3)) || ((Z > -295) && (Z <101) && (R > 160) && (norm(E2) < L2+L3))
                        app.ZonedetravailLamp.Color = 'green';
                        
                        % Calcul des thetas qui vont bien
                        % theta2 = acosd((norm(E)^2 - L1^2 - L2^2 )/(-2*L1*L2));
                        % theta1 = acosd((X - L2*cosd(theta2 - 90))/L1);
            
                        x0 = [x(1), x(2), x(3)];
                        clear x;

                        %x = fsolve(@(x)equation(app, x, L1, L2, L3, E(1), E(2)), x0);
                        x = fsolve(@(x)equation3R(app, x, L1, L2,L3, E(1), E(2)), x0);
                        
                        if(x(1) < 0)
                            app.ZonedetravailLamp_2.Color = 'red';
                            clear x;
                            x = fsolve(@(x)equation2R(app, x,L2,L3, E2(1), E2(2)), x0);
                            x = [0 x];
                        else
                            app.ZonedetravailLamp_2.Color = 'green';
                        end
            
                        theta1 = x(1);
                        theta2 = x(2);
                        theta3 = x(3);
            
                        app.Theta1EditField.Value = x(1);
                        app.Theta2EditField.Value = x(2);
                        app.Theta3EditField.Value = x(3);
            
                        OA = coords_OA(app, L1, theta1);
                        OE = coords_OE(app, L1, L2, theta1, theta2);
            
                        % Plot basique
            
                        % Repère orthonormal
                        app.UIAxes.DataAspectRatio = [1 1 1];
            
                        % Limites du graphe
                        app.UIAxes.XLim = [app.XminEditField.Value app.XmaxEditField.Value];
                        app.UIAxes.YLim = [app.YminEditField.Value app.YmaxEditField.Value];
            
                        %Plot
                        app.UIAxes.cla;
                        hold(app.UIAxes,'on')
            
                        % Zone atteignable par le premier bras
                        % th = 0:pi/50:2*pi;
                        % xunit = L1 * cos(th);
                        % yunit = L1 * sin(th);
                        % plot(app.UIAxes, xunit, yunit);
            
                        % Zone 2ème
                        % th = 0:pi/50:2*pi;
                        % xunit = L2 * cos(th) + X;
                        % yunit = L2 * sin(th) + Y;
                        % plot(app.UIAxes, xunit, yunit);
            
                        % Zone du robot
                        h = fill(app.UIAxes, [50,-50,-50,50],[0,0,-185, -185],[ 0.9100 0.4100 0.1700]);
                        h.FaceAlpha=1;
                        g = fill(app.UIAxes, [160,-160,-160,160],[-185,-185,-295, -295],[ 0.9100 0.4100 0.1700]);
                        g.FaceAlpha=1;
                        % Position cible
                        R_cible = [R, OA(1), OE(1)];
                        Z_cible = [Z, OA(2), OE(2)];
                        scatter(app.UIAxes, R_cible, Z_cible);
            
                        % On trace le bras entier
                        R_bras = [0, L1*cosd(theta1), L1*cosd(theta1) + L2*cosd(theta1 + theta2), L1*cosd(theta1) + L2*cosd(theta1 + theta2) + L3*cosd(theta1 + theta2 + theta3) ];
                        Z_bras = [0, L1*sind(theta1), L1*sind(theta1) + L2*sind(theta1 + theta2), L1*sind(theta1) + L2*sind(theta1 + theta2) + L3*sind(theta1 + theta2 + theta3) ];
                        plot(app.UIAxes, R_bras, Z_bras);
                        
                        pause(0.05);
                    else
                        app.ZonedetravailLamp.Color = 'red';
                        %return;
                    end
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'R')
            ylabel(app.UIAxes, 'Z')
            app.ZonedetravailLampLabel = uilabel(app.UIFigure);
            app.ZonedetravailLampLabel.Text = 'Zone de travail';
            app.ZonedetravailLamp = uilamp(app.UIFigure);
            app.LancerButton = uibutton(app.UIFigure, 'push');
            app.LancerButton.ButtonPushedFcn = createCallbackFcn(app, @LancerButtonPushed, true);
            app.LancerButton.Text = 'Lancer';
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditField.Value = 150;
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditField.Value = 150;
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XminEditField.Value = -200;
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditField.Value = 600;
            app.YminEditFieldLabel = uilabel(app.UIFigure);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.UIFigure, 'numeric');
            app.YminEditField.Value = -300;
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField.Value = 500;
            app.OverrunT1CheckBox = uicheckbox(app.UIFigure);
            app.OverrunT1CheckBox.Text = 'Overrun T1';
            app.OverrunT2CheckBox = uicheckbox(app.UIFigure);
            app.OverrunT2CheckBox.Text = 'Overrun T2';
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L3EditFieldLabel = uilabel(app.UIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.UIFigure, 'numeric');
            app.L3EditField.Value = 170;
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.OverrunT3CheckBox = uicheckbox(app.UIFigure);
            app.OverrunT3CheckBox.Text = 'Overrun T3';
            app.RSliderLabel = uilabel(app.UIFigure);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.UIFigure);
            app.RSlider.Limits = [0 400];
            app.RSlider.Value = 200;
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-295 469];
            app.ZSlider.Value = -290;
            app.LoopButton = uibutton(app.UIFigure, 'push');
            app.LoopButton.ButtonPushedFcn = createCallbackFcn(app, @LoopButtonPushed, true);
            app.LoopButton.Text = 'Loop';
            app.ZonedetravailLamp_2 = uilamp(app.UIFigure);
            app.PASEditFieldLabel = uilabel(app.UIFigure);
            app.PASEditFieldLabel.Text = 'PAS';
            app.PASEditField = uieditfield(app.UIFigure, 'numeric');
            app.PASEditField.Value = 150;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Affichage_Bras

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end