classdef Affichage_Bras_2R_3R < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        ZonedetravailLampLabel      matlab.ui.control.Label
        ZonedetravailLamp           matlab.ui.control.Lamp
        LancerButton                matlab.ui.control.Button
        XEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        L1EditFieldLabel            matlab.ui.control.Label
        L1EditField                 matlab.ui.control.NumericEditField
        L2EditFieldLabel            matlab.ui.control.Label
        L2EditField                 matlab.ui.control.NumericEditField
        XminEditFieldLabel          matlab.ui.control.Label
        XminEditField               matlab.ui.control.NumericEditField
        XmaxEditFieldLabel          matlab.ui.control.Label
        XmaxEditField               matlab.ui.control.NumericEditField
        YminEditFieldLabel          matlab.ui.control.Label
        YminEditField               matlab.ui.control.NumericEditField
        YmaxEditFieldLabel          matlab.ui.control.Label
        YmaxEditField               matlab.ui.control.NumericEditField
        OverrunT1CheckBox           matlab.ui.control.CheckBox
        OverrunT2CheckBox           matlab.ui.control.CheckBox
        Theta2EditFieldLabel        matlab.ui.control.Label
        Theta2EditField             matlab.ui.control.NumericEditField
        Theta1EditFieldLabel        matlab.ui.control.Label
        Theta1EditField             matlab.ui.control.NumericEditField
        ClearCheckBox               matlab.ui.control.CheckBox
        PrimtremaxiLampLabel        matlab.ui.control.Label
        PrimtremaxiLamp             matlab.ui.control.Lamp
        TailleRobotXEditFieldLabel  matlab.ui.control.Label
        TailleRobotXEditField       matlab.ui.control.NumericEditField
        TailleRobotYEditFieldLabel  matlab.ui.control.Label
        TailleRobotYEditField       matlab.ui.control.NumericEditField
        PrimtremaxiEditFieldLabel   matlab.ui.control.Label
        PrimtremaxiEditField        matlab.ui.control.NumericEditField
        L3EditFieldLabel            matlab.ui.control.Label
        L3EditField                 matlab.ui.control.NumericEditField
        OverrunT3CheckBox           matlab.ui.control.CheckBox
        Theta3EditFieldLabel        matlab.ui.control.Label
        Theta3EditField             matlab.ui.control.NumericEditField
        TypeDropDownLabel           matlab.ui.control.Label
        TypeDropDown                matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        function OA = coords_OA(~, L1, theta1)
            OA = [L1 * cosd(theta1), L1 * sind(theta1)];
        end
        
        function AE = coords_AE(~, L2, theta2)
            AE = [L2 * cosd(theta2 - 90), L2 * sind(theta2 - 90)];
        end
        
        function OE = coords_OE(app, L1, L2, theta1, theta2)
            OE = coords_OA(app, L1, theta1) + coords_AE(app, L2, theta2);
        end
    
        function F = equation2R(~, x, L1, L2, X, Y)
            F(1) = L1*cosd(x(1)) + L2*cosd(x(1) + x(2)) - X;
            F(2) = L1*sind(x(1)) + L2*sind(x(1) + x(2)) - Y;
        end
    
        function F = equation3R(~, x, L1, L2, L3, X, Y)
            F(1) = L1*cosd(x(1)) + L2*cosd(x(1) + x(2)) + L3*cosd(x(1) + x(2) + x(3)) - X;
            F(2) = L1*sind(x(1)) + L2*sind(x(1) + x(2)) + L3*sind(x(1) + x(2) + x(3)) - Y;
        end
    
        function update_UI(app)
            
            if(app.TypeDropDown.Value == "2 Rotations")
                app.Theta3EditField.Visible = false;
                app.Theta3EditFieldLabel.Visible = false;
                app.OverrunT3CheckBox.Visible = false;
                app.L3EditField.Visible = false;
                app.L3EditFieldLabel.Visible = false;
            else
                app.Theta3EditField.Visible = true;
                app.Theta3EditFieldLabel.Visible = true;
                app.OverrunT3CheckBox.Visible = true;
                app.L3EditField.Visible = true;
                app.L3EditFieldLabel.Visible = true;
       
            end
        end
    end
    

    methods (Access = private)

        % Callback function
        function XSpinnerValueChanged(app, event)

            
        end

        % Button pushed function: LancerButton
        function LancerButtonPushed(app, event)
            % Mise à jour du frontend
            update_UI(app);
            
            % Taille des bras du robot
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            if(app.TypeDropDown.Value == "2 Rotations")
                L3 = 0;
            else
                L3 = app.L3EditField.Value;
            end
            
            
            % Position cible
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            E = [X, Y];
            
            % Vérification si dans la zone de travail
            if (norm(E) < L1 + L2 + L3)
                app.ZonedetravailLamp.Color = 'green';
            else
               app.ZonedetravailLamp.Color = 'red';
               return;
            end
            
            if(app.TypeDropDown.Value == "2 Rotations")
                x0 = [0,0];
                x = fsolve(@(x)equation2R(app, x, L1, L2, X, Y), x0);
            else
                x0 = [0, 0, 0];
                % options = optimoptions('fsolve', 'MaxFunctionEvaluations', 1000);
                x = fsolve(@(x)equation3R(app, x, L1, L2,L3, X, Y), x0);
                theta3 = x(3);
                app.Theta3EditField.Value = theta3;
            end
            
            theta1 = x(1);
            theta2 = x(2);
            
            if(app.OverrunT1CheckBox.Value == true)
                theta1 = app.Theta1EditField.Value;
            else
                app.Theta1EditField.Value = theta1;
            end
            
            if(app.OverrunT2CheckBox.Value == true)
                theta2 = app.Theta2EditField.Value;
            else
                app.Theta2EditField.Value = theta2;
            end
            
            
            % Plot basique
            
            % Repère orthonormal
            app.UIAxes.DataAspectRatio = [1 1 1];
            
            % Limites du graphe
            app.UIAxes.XLim = [app.XminEditField.Value app.XmaxEditField.Value];
            app.UIAxes.YLim = [app.YminEditField.Value app.YmaxEditField.Value];
            
            %Plot
            
            if(app.ClearCheckBox.Value == 1)
                app.UIAxes.cla;                
            end
            
            
            hold(app.UIAxes,'on')
            
            % Zone atteignable par le premier bras
            % th = 0:pi/50:2*pi;
            % xunit = L1 * cos(th);
            % yunit = L1 * sin(th);
            % plot(app.UIAxes, xunit, yunit);
            
            % Zone 2ème
            % th = 0:pi/50:2*pi;
            % xunit = L2 * cos(th) + X;
            % yunit = L2 * sin(th) + Y;
            % plot(app.UIAxes, xunit, yunit);
            
            % Limite accessible par le bras
            % th1 = 0:pi/50:pi;
            % th2 = 3*pi/2:pi/50:2*pi;
            % th = [th2, th1];
            % xunit = (L1+L2) * cos(th);
            % yunit = (L1+L2) * sin(th);
            % plot(app.UIAxes, xunit, yunit, 'red');
            
            
            % Zone du robot
            h = fill(app.UIAxes, [0,-10,-10,0],[0,0,-10, -10],[ 0.9100 0.4100 0.1700]);
            h.FaceAlpha=1;
            % Position cible
            scatter(app.UIAxes, X, Y);
            
            % On trace le bras entier
            if(app.TypeDropDown.Value == "2 Rotations")
                X_bras = [0, L1*cosd(theta1), L1*cosd(theta1) + L2*cosd(theta1 + theta2), L1*cosd(theta1) + L2*cosd(theta1 + theta2)];
                Y_bras = [0, L1*sind(theta1), L1*sind(theta1) + L2*sind(theta1 + theta2), L1*sind(theta1) + L2*sind(theta1 + theta2)];           
            else
                X_bras = [0, L1*cosd(theta1), L1*cosd(theta1) + L2*cosd(theta1 + theta2), L1*cosd(theta1) + L2*cosd(theta1 + theta2) + L3*cosd(theta1 + theta2 + theta3) ];
                Y_bras = [0, L1*sind(theta1), L1*sind(theta1) + L2*sind(theta1 + theta2), L1*sind(theta1) + L2*sind(theta1 + theta2) + L3*sind(theta1 + theta2 + theta3) ];
            end
            plot(app.UIAxes, X_bras, Y_bras);


        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ZonedetravailLampLabel = uilabel(app.UIFigure);
            app.ZonedetravailLampLabel.Text = 'Zone de travail';
            app.ZonedetravailLamp = uilamp(app.UIFigure);
            app.LancerButton = uibutton(app.UIFigure, 'push');
            app.LancerButton.ButtonPushedFcn = createCallbackFcn(app, @LancerButtonPushed, true);
            app.LancerButton.Text = 'Lancer';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Value = 5;
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Value = 5;
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditField.Value = 5;
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditField.Value = 5;
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XminEditField.Value = -15;
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditField.Value = 15;
            app.YminEditFieldLabel = uilabel(app.UIFigure);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.UIFigure, 'numeric');
            app.YminEditField.Value = -15;
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField.Value = 15;
            app.OverrunT1CheckBox = uicheckbox(app.UIFigure);
            app.OverrunT1CheckBox.Text = 'Overrun T1';
            app.OverrunT2CheckBox = uicheckbox(app.UIFigure);
            app.OverrunT2CheckBox.Text = 'Overrun T2';
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearCheckBox = uicheckbox(app.UIFigure);
            app.ClearCheckBox.Text = 'Clear';
            app.ClearCheckBox.Value = true;
            app.PrimtremaxiLampLabel = uilabel(app.UIFigure);
            app.PrimtremaxiLampLabel.Text = 'Périmètre maxi';
            app.PrimtremaxiLamp = uilamp(app.UIFigure);
            app.TailleRobotXEditFieldLabel = uilabel(app.UIFigure);
            app.TailleRobotXEditFieldLabel.Text = 'Taille Robot X';
            app.TailleRobotXEditField = uieditfield(app.UIFigure, 'numeric');
            app.TailleRobotXEditField.Value = 300;
            app.TailleRobotYEditFieldLabel = uilabel(app.UIFigure);
            app.TailleRobotYEditFieldLabel.Text = 'Taille Robot Y';
            app.TailleRobotYEditField = uieditfield(app.UIFigure, 'numeric');
            app.TailleRobotYEditField.Value = 300;
            app.PrimtremaxiEditFieldLabel = uilabel(app.UIFigure);
            app.PrimtremaxiEditFieldLabel.Text = 'Périmètre maxi';
            app.PrimtremaxiEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimtremaxiEditField.Value = 1300;
            app.L3EditFieldLabel = uilabel(app.UIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.UIFigure, 'numeric');
            app.L3EditField.Value = 6;
            app.OverrunT3CheckBox = uicheckbox(app.UIFigure);
            app.OverrunT3CheckBox.Text = 'Overrun T3';
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'2 Rotations', '3 Rotations'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '2 Rotations';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Affichage_Bras_2R_3R

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end